classdef VollImBilde < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WebcamwhlenDropDown       matlab.ui.control.DropDown
        WebcamwhlenDropDownLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        mitte                     matlab.ui.control.Button
        FolgenButton              matlab.ui.control.StateButton
        h1VollimBildeV01h1Label   matlab.ui.control.Label
        untenrechts               matlab.ui.control.Button
        untenlinks                matlab.ui.control.Button
        obenrechts                matlab.ui.control.Button
        obenlinks                 matlab.ui.control.Button
        canvas                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cam % WebCamobjekt
        Camlist
        Ardu %Arduinoobjekt
        Pan %Servoobjekt für Panbewegung
        Tilt %Servoobjekt für Tiltbewegung
        Detector %Bilderkennung
        VisionType = 'peopleACF'; % Bildererkennungsalgo
        Mounting % Davon hängen auch Buttons(Sollwerte) ab!
        Follow = 0; %An/Aus 
    end
    
    methods (Access = private)
        function mainLoop(app)
            while(1)
                %Bilderkennung und Ausgabe
                if(app.Follow)
                    %Regelung
                    %ToDo:Controllerobjekt 
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Arduino initialisieren
            app.Ardu = arduino('COM6', 'Nano33IoT', 'Libraries', 'Servo'); %evtl auch noch als Dropdown?
            app.Pan = servo(app.Ardu, 'D2');
            app.Tilt = servo(app.Ardu, 'D3');
            writePosition(app.Tilt, 0.5);
            writePosition(app.Pan, 0.35);
            %Dropdown für WebCam initalisieren
            app.Camlist = webcamlist;
            app.WebcamwhlenDropDown.Items = app.Camlist;
            app.WebcamwhlenDropDown.Value = app.Camlist(1);
            %Webcam initalisieren
            app.Cam = webcam(1);
            %Bilderkennung initalisieren
            app.Detector = ImagePeopleDetector(app.VisionType, 1);
            
            %Mainloop starten
            app.mainLoop();
        end

        % Value changed function: WebcamwhlenDropDown
        function WebcamwhlenDropDownValueChanged(app, event)
            value = app.WebcamwhlenDropDown.Value;
            webcamNum = find(ismember(app.Camlist, value));
            app.Cam = webcam(webcamNum);
        end

        % Drop down opening function: WebcamwhlenDropDown
        function WebcamwhlenDropDownOpening(app, event)
            app.Camlist = webcamlist;
            app.WebcamwhlenDropDown.Items = app.Camlist;
        end

        % Value changed function: FolgenButton
        function FolgenButtonValueChanged(app, event)
            value = app.FolgenButton.Value;
            app.Follow = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.canvas = uiaxes(app.UIFigure);
            title(app.canvas, 'Title')
            xlabel(app.canvas, 'X')
            ylabel(app.canvas, 'Y')
            zlabel(app.canvas, 'Z')
            app.obenlinks = uibutton(app.UIFigure, 'push');
            app.obenlinks.Text = 'oben links';
            app.obenrechts = uibutton(app.UIFigure, 'push');
            app.obenrechts.Text = 'oben rechts';
            app.untenlinks = uibutton(app.UIFigure, 'push');
            app.untenlinks.Text = 'unten links';
            app.untenrechts = uibutton(app.UIFigure, 'push');
            app.untenrechts.Text = 'unten rechts';
            app.h1VollimBildeV01h1Label = uilabel(app.UIFigure);
            app.h1VollimBildeV01h1Label.Interpreter = 'html';
            app.h1VollimBildeV01h1Label.Text = '<h1>Voll im Bilde V0.1.<h1>';
            app.FolgenButton = uibutton(app.UIFigure, 'state');
            app.FolgenButton.ValueChangedFcn = createCallbackFcn(app, @FolgenButtonValueChanged, true);
            app.FolgenButton.Text = 'Folgen';
            app.mitte = uibutton(app.UIFigure, 'push');
            app.mitte.Text = 'mitte';
            app.Image = uiimage(app.UIFigure);
            app.WebcamwhlenDropDownLabel = uilabel(app.UIFigure);
            app.WebcamwhlenDropDownLabel.Text = 'Webcam wählen ..';
            app.WebcamwhlenDropDown = uidropdown(app.UIFigure);
            app.WebcamwhlenDropDown.Items = {'...'};
            app.WebcamwhlenDropDown.DropDownOpeningFcn = createCallbackFcn(app, @WebcamwhlenDropDownOpening, true);
            app.WebcamwhlenDropDown.ValueChangedFcn = createCallbackFcn(app, @WebcamwhlenDropDownValueChanged, true);
            app.WebcamwhlenDropDown.Value = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VollImBilde

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end